classdef TestApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        IncludeintestLabel  matlab.ui.control.Label
        CheckBox6           matlab.ui.control.CheckBox
        CheckBox5           matlab.ui.control.CheckBox
        CheckBox4           matlab.ui.control.CheckBox
        CheckBox3           matlab.ui.control.CheckBox
        DropDown5           matlab.ui.control.DropDown
        Label3              matlab.ui.control.Label
        DropDown4           matlab.ui.control.DropDown
        Label2              matlab.ui.control.Label
        Panel5              matlab.ui.container.Panel
        NumericEditField12  matlab.ui.control.NumericEditField
        NumericEditField11  matlab.ui.control.NumericEditField
        NumericEditField10  matlab.ui.control.NumericEditField
        Panel4              matlab.ui.container.Panel
        NumericEditField9   matlab.ui.control.NumericEditField
        NumericEditField8   matlab.ui.control.NumericEditField
        NumericEditField7   matlab.ui.control.NumericEditField
        Button6             matlab.ui.control.Button
        Button5             matlab.ui.control.Button
        TextArea            matlab.ui.control.TextArea
        Panel3              matlab.ui.container.Panel
        CheckBox8           matlab.ui.control.CheckBox
        CheckBox7           matlab.ui.control.CheckBox
        CheckBox2           matlab.ui.control.CheckBox
        CheckBox            matlab.ui.control.CheckBox
        DropDown3           matlab.ui.control.DropDown
        ChosedatasetLabel   matlab.ui.control.Label
        Panel2              matlab.ui.container.Panel
        NumericEditField6   matlab.ui.control.NumericEditField
        NumericEditField5   matlab.ui.control.NumericEditField
        NumericEditField4   matlab.ui.control.NumericEditField
        Button4             matlab.ui.control.Button
        Panel               matlab.ui.container.Panel
        NumericEditField3   matlab.ui.control.NumericEditField
        NumericEditField2   matlab.ui.control.NumericEditField
        NumericEditField    matlab.ui.control.NumericEditField
        Button3             matlab.ui.control.Button
        Button2             matlab.ui.control.Button
        DropDown2           matlab.ui.control.DropDown
        Label               matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
        LabelDropDown       matlab.ui.control.Label
        Button              matlab.ui.control.Button
        UIAxes5             matlab.ui.control.UIAxes
        UIAxes4             matlab.ui.control.UIAxes
        UIAxes3             matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end


    properties (Access = public)
        AHRS;
        
        errorProgress1;
        intProg1=1;
        errorProgress2;
        intProg2=1;        
        errorProgress3;
        intProg3=1;
        errorProgress4;
        intProg4=1;
        
        stopOpt=0;
        
        dataset=1;
        RMS=0;
        IMU=0;
        exceptPeak=0;

        optimAttempts=0;
        optimFreezeParam=0;
    end
    
    methods (Access = private)
        function valueMathodChange(app,className, num, val1, val2, val3)
            switch(className)
                case 'MadgwickAHRS3'
                    app.AHRS{num}.Beta=val1;
                case 'MadgwickAHRSclanek'
                    app.AHRS{num}.Beta=val1;
                case 'JustaAHRSPureFast'
                    app.AHRS{num}.gain=val1;
                    app.AHRS{num}.wAcc=val2;
                    app.AHRS{num}.wMag=val3;
                case 'Valenti_AHRS'
                    app.AHRS{num}.wAcc=val1;
                    app.AHRS{num}.wMag=val2;
                case 'JustaAHRSPureFastConstantCorr'
                    app.AHRS{num}.wAcc=val1;
                    app.AHRS{num}.wMag=val2;
                case 'JustaAHRSPureFastLinearCorr'
                    app.AHRS{num}.gain=val1;
                    app.AHRS{num}.wAcc=val2;
                    app.AHRS{num}.wMag=val3;
                case 'JustaAHRSPure'
                    app.AHRS{num}.wAcc=val1;
                    app.AHRS{num}.wMag=val2;
                case 'JinWuKF_AHRSreal2'
                    app.AHRS{num}.Sigma_g=diag([1 1 1])*val1;
                    app.AHRS{num}.Sigma_a=diag([1 1 1])*val2;
                    app.AHRS{num}.Sigma_m=diag([1 1 1])*val3;
                case 'YoungSooSuh_AHRS'
                    app.AHRS{num}.rg=val1;
                    app.AHRS{num}.ra=val2;
            end
        end
        
        
        function valChange1(app,src,event)            
            className=class(app.AHRS1);
            v1=app.NumericEditField.Value;
            v2=app.NumericEditField2.Value;
            v3=app.NumericEditField3.Value;
            valueMathodChange(app, className, 1, v1, v2, v3);
        end
        
        function valChange2(app,src,event)
            className=class(app.AHRS2);
            v1=app.NumericEditField4.Value;
            v2=app.NumericEditField5.Value;
            v3=app.NumericEditField6.Value;
            valueMathodChange(app, className, 2, v1, v2, v3);
        end
        
        function valChange3(app,src,event)
            className=class(app.AHRS3);
            v1=app.NumericEditField7.Value;
            v2=app.NumericEditField8.Value;
            v3=app.NumericEditField9.Value;
            valueMathodChange(app, className, 3, v1, v2, v3);            
        end
        
        function valChange4(app,src,event)
            className=class(app.AHRS4);
            v1=app.NumericEditField10.Value;
            v2=app.NumericEditField11.Value;
            v3=app.NumericEditField12.Value;
            valueMathodChange(app, className, 4, v1, v2, v3);            
        end


        function methodChanged(app, className, num)
            
            switch(className)
                case 'MadgwickModif'
                    app.AHRS{num} = MadgwickAHRS3();
                case 'Madgwick'
                    app.AHRS{num} = MadgwickAHRSclanek();
                case 'JustaFast'
                    app.AHRS{num} = JustaAHRSPureFast();
                case 'JustaPure'
                    app.AHRS{num} = JustaAHRSPure();
                case 'JustaLinear'
                    app.AHRS{num} = JustaAHRSPureFastLinearCorr();
                case 'JustaConstant'
                    app.AHRS{num} = JustaAHRSPureFastConstantCorr();
                case 'Valenti'
                    app.AHRS{num} = Valenti_AHRS();
                case 'Guo'
                    app.AHRS{num} = JinWuKF_AHRSreal2();
                case 'YoungSooSuh'
                    app.AHRS{num} = YoungSooSuh_AHRS();
                case 'Wilson'
                    app.AHRS{num} = Wilson_Madgwick_AHRS();
                case 'Admirall'
                    app.AHRS{num} = AdmirallWilsonAHRS();
            end
        end
        
        
        function updateMainPlot(app)            
                plot(app.UIAxes,1:length(app.errorProgress1),app.errorProgress1);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,1:length(app.errorProgress2),app.errorProgress2);
                plot(app.UIAxes,1:length(app.errorProgress3),app.errorProgress3);
                plot(app.UIAxes,1:length(app.errorProgress4),app.errorProgress4);
                hold(app.UIAxes,'off');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            

            addpath('./Filters/');
            addpath('./Datasets/');
            addpath('./quaternion_library/');
            app.AHRS={MadgwickAHRS3(), JustaAHRSPureFast(), Valenti_AHRS(), JinWuKF_AHRSreal2()};                  
            
            app.NumericEditField.Value=app.AHRS{1}.Beta;
            app.NumericEditField4.Value=app.AHRS{2}.gain;
            app.NumericEditField5.Value=app.AHRS{2}.wAcc;
            app.NumericEditField6.Value=app.AHRS{2}.wMag;
            app.NumericEditField7.Value=app.AHRS{3}.wAcc;
            app.NumericEditField8.Value=app.AHRS{3}.wMag;
            app.NumericEditField10.Value=app.AHRS{4}.Sigma_a(1,1);
            app.NumericEditField11.Value=app.AHRS{4}.Sigma_m(1,1);
            
            app.NumericEditField.ValueChangedFcn=@app.valChange1;
            app.NumericEditField2.ValueChangedFcn=@app.valChange1;
            app.NumericEditField3.ValueChangedFcn=@app.valChange1;
            app.NumericEditField4.ValueChangedFcn=@app.valChange2;
            app.NumericEditField5.ValueChangedFcn=@app.valChange2;
            app.NumericEditField6.ValueChangedFcn=@app.valChange2;            
            app.NumericEditField7.ValueChangedFcn=@app.valChange3;
            app.NumericEditField8.ValueChangedFcn=@app.valChange3;
            app.NumericEditField9.ValueChangedFcn=@app.valChange3;
            app.NumericEditField10.ValueChangedFcn=@app.valChange4;
            app.NumericEditField11.ValueChangedFcn=@app.valChange4;
            app.NumericEditField12.ValueChangedFcn=@app.valChange4;
        end

        % Button pushed function: Button
        function startApp(app, event)
            
            evalin('base','clear');
            
            enabled=[app.CheckBox3.Value, app.CheckBox4.Value ,app.CheckBox5.Value, app.CheckBox6.Value];            
            res = {''};            
            if(any(enabled~=0))
                for i=1:4
                    if(enabled(i))
                        [valErr, uhel,time]=datasetTestingFunctionSingle(app.AHRS{i},app.dataset,app.RMS,app.IMU, app.exceptPeak);
                        res{end+1} =strcat('Method', num2str(i),' error:  ',num2str(valErr,'%2.6f'));
                        assignin('base', strcat('M',num2str(i) ,'uhel'), uhel);                        
                    end
                end
                assignin('base','time',time);
            else
                res ='No method checked for testing';
            end
            
            app.TextArea.Value=res;
        end

        % Value changed function: DropDown
        function methodOneChanged(app, event)
            value = app.DropDown.Value;
            app.errorProgress1=0;
            app.intProg1=1;
            methodChanged(app, value, 1);
            
        end

        % Value changed function: DropDown2
        function methodTwoChanged(app, event)
            value = app.DropDown2.Value;            
            app.errorProgress2=0;
            app.intProg2=1;
            methodChanged(app, value, 2);
        end

        % Value changed function: DropDown4
        function Method3Changed(app, event)
            value = app.DropDown4.Value;            
            app.errorProgress3=0;
            app.intProg3=1;            
            methodChanged(app, value, 3);
        end

        % Value changed function: DropDown5
        function Method4Changed(app, event)
            value = app.DropDown5.Value;
            app.errorProgress4=0;
            app.intProg4=1;
            methodChanged(app, value, 4);
        end

        % Button pushed function: Button4
        function stop(app, event)
            app.stopOpt=1;
        end

        % Button pushed function: Button2
        function OptimizeM1(app, event)
            
            app.Button2.Text='Wait';
            app.Button4.Text= 'Stop Optimization';
            while(true)
                [app.AHRS{1},e,param,app.optimAttempts,app.optimFreezeParam]=optimFilterParams(app.AHRS{1},app.RMS,app.dataset,app.optimAttempts,app.optimFreezeParam,app.IMU);
                app.errorProgress1(app.intProg1)=e;
                app.intProg1=app.intProg1+1;
                
                updateMainPlot(app);
                plot(app.UIAxes2,1:length(app.errorProgress1),app.errorProgress1);
                
                app.NumericEditField.Value=param(1);
                app.NumericEditField2.Value=param(2);
                app.NumericEditField3.Value=param(3);
                pause(0.5);
                if(app.stopOpt)
                    app.Button4.Text = '';
                    app.stopOpt=0;
                    break;
                end
            end
            app.optimAttempts=0;
            app.optimFreezeParam=0;
            
            app.Button2.Text='Optimize';

        end

        % Button pushed function: Button3
        function OptimizeM2(app, event)
            app.Button3.Text='Wait';
            app.Button4.Text= 'Stop Optimization';
            while(true)
                [app.AHRS{2},e,param,app.optimAttempts,app.optimFreezeParam]=optimFilterParams(app.AHRS{2},app.RMS,app.dataset,app.optimAttempts,app.optimFreezeParam,app.IMU);
                
                app.errorProgress2(app.intProg2)=e;
                app.intProg2=app.intProg2+1;
                
                updateMainPlot(app);
                plot(app.UIAxes3,1:length(app.errorProgress2),app.errorProgress2);
                
                app.NumericEditField4.Value=param(1);
                app.NumericEditField5.Value=param(2);
                app.NumericEditField6.Value=param(3);
                pause(0.5);
                if(app.stopOpt)
                    app.Button4.Text = '';
                    app.stopOpt=0;
                    break;
                end
            end
            app.optimAttempts=0;
            app.optimFreezeParam=0;
            
            app.Button3.Text='Optimize';
        end

        % Button pushed function: Button5
        function OptimizeM3(app, event)
            app.Button5.Text='Wait';
            app.Button4.Text= 'Stop Optimization';
            while(true)
                [app.AHRS{3},e,param,app.optimAttempts,app.optimFreezeParam]=optimFilterParams(app.AHRS{3},app.RMS,app.dataset,app.optimAttempts,app.optimFreezeParam,app.IMU);
                
                app.errorProgress3(app.intProg3)=e;
                app.intProg3=app.intProg3+1;
                updateMainPlot(app);
                plot(app.UIAxes4,1:length(app.errorProgress3),app.errorProgress3);
                
                app.NumericEditField7.Value=param(1);
                app.NumericEditField8.Value=param(2);
                app.NumericEditField9.Value=param(3);
                pause(0.5);
                if(app.stopOpt)
                    app.Button4.Text = '';
                    app.stopOpt=0;
                    break;
                end
            end
            app.optimAttempts=0;
            app.optimFreezeParam=0;
            
            app.Button5.Text='Optimize';
        end

        % Button pushed function: Button6
        function OptimizeM4(app, event)
            app.Button6.Text='Wait';
            app.Button4.Text= 'Stop Optimization';
            while(true)
                [app.AHRS{4},e,param,app.optimAttempts,app.optimFreezeParam]=optimFilterParams(app.AHRS{4},app.RMS,app.dataset,app.optimAttempts,app.optimFreezeParam,app.IMU);
                
                app.errorProgress4(app.intProg4)=e;
                app.intProg4=app.intProg4+1;
                
                updateMainPlot(app);
                plot(app.UIAxes5,1:length(app.errorProgress4),app.errorProgress4);
                
                app.NumericEditField10.Value=param(1);
                app.NumericEditField11.Value=param(2);
                app.NumericEditField12.Value=param(3);
                pause(0.5);
                if(app.stopOpt)
                    app.Button4.Text = '';
                    app.stopOpt=0;
                    break;
                end
            end
            app.optimAttempts=0;
            app.optimFreezeParam=0;
            
            app.Button6.Text='Optimize';
        end

        % Value changed function: DropDown3
        function datasetChanged(app, event)
            value = app.DropDown3.Value;
            switch(value)
                case 'Set1'
                    app.dataset=1;
                case 'Set2'
                    app.dataset=2;
                case 'Set12'
                    app.dataset=3;
                case 'Set123'
                    app.dataset=4;
                case 'ALSdataset'
                    app.dataset=5;
                case 'Synthetic2'
                    app.dataset=6;
                case 'Synthetic3'
                    app.dataset=7;
            end
            app.errorProgress1=0;
            app.intProg1=1;
            app.errorProgress2=0;
            app.intProg2=1;
            app.errorProgress3=0;
            app.intProg3=1;
            app.errorProgress4=0;
            app.intProg4=1;
        end

        % Value changed function: CheckBox
        function RMSchanged(app, event)
            app.RMS = app.CheckBox.Value;
            app.errorProgress1=0;
            app.intProg1=1;
            app.errorProgress2=0;
            app.intProg2=1;
            
            app.errorProgress3=0;
            app.intProg3=1;
            app.errorProgress4=0;
            app.intProg4=1;
        end

        % Value changed function: CheckBox7
        function IMUchange(app, event)
            value = app.CheckBox7.Value;
            app.IMU=value;
        end

        % Value changed function: CheckBox8
        function ExceptChange(app, event)
            app.exceptPeak=app.CheckBox8.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Compare AHRS method GUI';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Campare optimization')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Method 1')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Method 2')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Method 3')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Method 4')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @startApp, true);
            app.Button.Text = 'Start test';
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Method1';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Madgwick', 'JustaFast', 'Valenti', 'Guo', 'MadgwickModif', 'JustaPure', 'JustaLinear', 'JustaConstant', 'YoungSooSuh'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @methodOneChanged, true);
            app.DropDown.Value = 'MadgwickModif';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Method2';
            app.DropDown2 = uidropdown(app.UIFigure);
            app.DropDown2.Items = {'Madgwick', 'JustaFast', 'Valenti', 'Guo', 'MadgwickModif', 'JustaPure', 'JustaLinear', 'JustaConstant', 'YoungSooSuh'};
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @methodTwoChanged, true);
            app.DropDown2.Value = 'JustaFast';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @OptimizeM1, true);
            app.Button2.Text = 'Optimize';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @OptimizeM2, true);
            app.Button3.Text = 'Optimize';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.NumericEditField = uieditfield(app.Panel, 'numeric');
            app.NumericEditField2 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField3 = uieditfield(app.Panel, 'numeric');
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @stop, true);
            app.Button4.Text = '';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel';
            app.NumericEditField4 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField5 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField6 = uieditfield(app.Panel2, 'numeric');
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Panel';
            app.ChosedatasetLabel = uilabel(app.Panel3);
            app.ChosedatasetLabel.Text = 'Chose dataset';
            app.DropDown3 = uidropdown(app.Panel3);
            app.DropDown3.Items = {'Set1', 'Set2', 'Set12', 'Set123', 'ALSdataset', 'Synthetic2', 'Synthetic3'};
            app.DropDown3.ValueChangedFcn = createCallbackFcn(app, @datasetChanged, true);
            app.DropDown3.Value = 'Set1';
            app.CheckBox = uicheckbox(app.Panel3);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @RMSchanged, true);
            app.CheckBox.Tooltip = {'Mean square error'};
            app.CheckBox.Text = 'MSE';
            app.CheckBox2 = uicheckbox(app.Panel3);
            app.CheckBox2.Tooltip = {'The error estimated from euler angles.'};
            app.CheckBox2.Text = 'Euler';
            app.CheckBox7 = uicheckbox(app.Panel3);
            app.CheckBox7.ValueChangedFcn = createCallbackFcn(app, @IMUchange, true);
            app.CheckBox7.Tooltip = {'Only accelerometer and gyro filter.'};
            app.CheckBox7.Text = 'IMU';
            app.CheckBox8 = uicheckbox(app.Panel3);
            app.CheckBox8.ValueChangedFcn = createCallbackFcn(app, @ExceptChange, true);
            app.CheckBox8.Tooltip = {'There is significant error peak with all filters in set 2, which can be caused by desynchronization of reference data. Check to remove this peak from error estimation.'};
            app.CheckBox8.Text = 'Except Peak';
            app.TextArea = uitextarea(app.UIFigure);
            app.Button5 = uibutton(app.UIFigure, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @OptimizeM3, true);
            app.Button5.Text = 'Optimize';
            app.Button6 = uibutton(app.UIFigure, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @OptimizeM4, true);
            app.Button6.Text = 'Optimize';
            app.Panel4 = uipanel(app.UIFigure);
            app.Panel4.Title = 'Panel';
            app.NumericEditField7 = uieditfield(app.Panel4, 'numeric');
            app.NumericEditField8 = uieditfield(app.Panel4, 'numeric');
            app.NumericEditField9 = uieditfield(app.Panel4, 'numeric');
            app.Panel5 = uipanel(app.UIFigure);
            app.Panel5.Title = 'Panel';
            app.NumericEditField10 = uieditfield(app.Panel5, 'numeric');
            app.NumericEditField11 = uieditfield(app.Panel5, 'numeric');
            app.NumericEditField12 = uieditfield(app.Panel5, 'numeric');
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Method3';
            app.DropDown4 = uidropdown(app.UIFigure);
            app.DropDown4.Items = {'Madgwick', 'JustaFast', 'Valenti', 'Guo', 'MadgwickModif', 'JustaPure', 'JustaLinear', 'JustaConstant', 'YoungSooSuh', 'Wilson'};
            app.DropDown4.ValueChangedFcn = createCallbackFcn(app, @Method3Changed, true);
            app.DropDown4.Value = 'Valenti';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Method4';
            app.DropDown5 = uidropdown(app.UIFigure);
            app.DropDown5.Items = {'Madgwick', 'JustaFast', 'Valenti', 'Guo', 'MadgwickModif', 'JustaPure', 'JustaLinear', 'JustaConstant', 'YoungSooSuh', 'Admirall'};
            app.DropDown5.ValueChangedFcn = createCallbackFcn(app, @Method4Changed, true);
            app.DropDown5.Value = 'Guo';
            app.CheckBox3 = uicheckbox(app.UIFigure);
            app.CheckBox3.Text = '';
            app.CheckBox4 = uicheckbox(app.UIFigure);
            app.CheckBox4.Text = '';
            app.CheckBox5 = uicheckbox(app.UIFigure);
            app.CheckBox5.Text = '';
            app.CheckBox6 = uicheckbox(app.UIFigure);
            app.CheckBox6.Text = '';
            app.IncludeintestLabel = uilabel(app.UIFigure);
            app.IncludeintestLabel.Text = {'Include'; 'in test'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestApplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end